classdef battleShipsGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        TabGroup                      matlab.ui.container.TabGroup
        GameAreaTab                   matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        GridLayout3                   matlab.ui.container.GridLayout
        GameMenuPanel                 matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        StartGameButton               matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        GameStatePanel                matlab.ui.container.Panel
        GridLayout6                   matlab.ui.container.GridLayout
        GameStateArea                 matlab.ui.control.TextArea
        Label_2                       matlab.ui.control.Label
        GridLayout4                   matlab.ui.container.GridLayout
        ShipsChoicePanel              matlab.ui.container.Panel
        GridLayout7                   matlab.ui.container.GridLayout
        ShipsListBox                  matlab.ui.control.ListBox
        GridLayout8                   matlab.ui.container.GridLayout
        PlayerBoardColumnDropdown     matlab.ui.control.DropDown
        OrientacelodiDropDownLabel    matlab.ui.control.Label
        PlayerBoardRowDropdown        matlab.ui.control.DropDown
        dekdeskyDropDownLabel         matlab.ui.control.Label
        SloupecdeskyDropDownLabel     matlab.ui.control.Label
        PlayerBoardShipOrientation    matlab.ui.control.DropDown
        GridLayout9                   matlab.ui.container.GridLayout
        AddShipButton                 matlab.ui.control.Button
        RemoveLastAddedShipButton     matlab.ui.control.Button
        RemoveAllAddedShipsButton     matlab.ui.control.Button
        PlayerGameBoardPanel          matlab.ui.container.Panel
        GridLayout10                  matlab.ui.container.GridLayout
        PlayerGameBoardUIAxes         matlab.ui.control.UIAxes
        EnemyShipsHitsPanel           matlab.ui.container.Panel
        GridLayout11                  matlab.ui.container.GridLayout
        GridLayout12                  matlab.ui.container.GridLayout
        EnemyShipsHitsColumnDropdown  matlab.ui.control.DropDown
        EnemyShipsHitsRowDropdown     matlab.ui.control.DropDown
        dekdeskyDropDown_2Label       matlab.ui.control.Label
        SloupecdeskyDropDown_2Label   matlab.ui.control.Label
        ShootButton                   matlab.ui.control.Button
        EnemyShipsHitsUIAxes          matlab.ui.control.UIAxes
        MadeMovesHistoryTab           matlab.ui.container.Tab
        GridLayout13                  matlab.ui.container.GridLayout
        MadeMovesHistoryTextArea      matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        databaseTablesIndexers = struct("shipTypes", 1, "ships", 2, "players", 3);
        database = {[   
                        struct( ...
                                "id", uint32(1), ...
                                "name", "Ponorka",...
                                "color", [1 0 0], ...
                                "shape", uint32(zeros(1, 5)) ...
                              );
                              
                        struct( ...
                                "id", uint32(2), ...
                                "name", "Torpédoborec",...
                                "color", [1 0 0], ...
                                "shape", uint32(zeros(1, 5)) ...
                              );

                        struct( ...
                                "id", uint32(3), ...
                                "name", "Křižník",...
                                "color", [1 0 0], ...
                                "shape", uint32(zeros(1, 5)) ...
                              );

                        struct( ...
                                "id", uint32(4), ...
                                "name", "Bitevní loď",...
                                "color", [1 0 0], ...
                                "shape", uint32(zeros(1, 5)) ...
                              );

                        struct( ...
                                "id", uint32(5), ...
                                "name", "Letadlová loď",...
                                "color", [1 0 0], ...
                                "shape", uint32(zeros(1, 5)) ...
                              ); 
                    ];
                    [
                        struct( ...
                                "id", uint32(1), ...
                                "coordinates", double.empty(0, 2),...
                                "health", logical.empty(0, 1), ...
                                "ownerId", uint32(2), ...
                                "shipTypeId", uint32(3) ...
                              );
                    ];
                    [
                        struct( ...
                                "id", uint32(1), ...
                                "name", "Hráč" ...
                              );

                        struct( ...
                                "id", uint32(1), ...
                                "name", "Nepřítel" ...
                              );
                    ]
                   };

        constantsIndexers = struct("water", 1, "waterColor", 2);
        constants = {
                        uint32(0);
                        [0 0 1]
                    };

        playerBoard = uint32(zeros(10, 10));
        enemyBoard = uint32(zeros(10, 10));
        playerColorBoard;
        enemyShipsHitsColorBoard;
        

        enemyCoordinatesMemory;
        playerShipsBoardAdditionOrder;


    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
        end

        % Value changed function: ShipsListBox
        function ShipsListBoxValueChanged(app, event)
            value = app.ShipsListBox.Value;
            
            if isempty(value)

                app.AddShipButton.Enable = "off";
            else
                app.AddShipButton.Enable = "on";
            end
        end

        % Button pushed function: AddShipButton
        function AddShipButtonPushed(app, event)
            
        end

        % Button pushed function: RemoveLastAddedShipButton
        function RemoveLastAddedShipButtonPushed(app, event)
            
        end

        % Button pushed function: RemoveAllAddedShipsButton
        function RemoveAllAddedShipsButtonPushed(app, event)
            
        end

        % Button pushed function: ShootButton
        function ShootButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.GameAreaTab = uitab(app.TabGroup);
            app.GameAreaTab.Title = 'HERNÍ PLOCHA';
            app.GridLayout2 = uigridlayout(app.GameAreaTab);
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.EnemyShipsHitsPanel = uipanel(app.GridLayout4);
            app.EnemyShipsHitsPanel.Title = 'ZÁSAHY LODÍ NEPŘÍTELE';
            app.GridLayout11 = uigridlayout(app.EnemyShipsHitsPanel);
            app.EnemyShipsHitsUIAxes = uiaxes(app.GridLayout11);
            app.EnemyShipsHitsUIAxes.XTick = [];
            app.EnemyShipsHitsUIAxes.YTick = [];
            app.EnemyShipsHitsUIAxes.ZTickLabel = '';
            app.ShootButton = uibutton(app.GridLayout11, 'push');
            app.ShootButton.ButtonPushedFcn = createCallbackFcn(app, @ShootButtonPushed, true);
            app.ShootButton.Text = 'Vystřelit';
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.SloupecdeskyDropDown_2Label = uilabel(app.GridLayout12);
            app.SloupecdeskyDropDown_2Label.Text = 'Sloupec desky';
            app.dekdeskyDropDown_2Label = uilabel(app.GridLayout12);
            app.dekdeskyDropDown_2Label.Text = 'Řádek desky';
            app.EnemyShipsHitsRowDropdown = uidropdown(app.GridLayout12);
            app.EnemyShipsHitsColumnDropdown = uidropdown(app.GridLayout12);
            app.PlayerGameBoardPanel = uipanel(app.GridLayout4);
            app.PlayerGameBoardPanel.Title = 'TVOJE HERNÍ DESKA';
            app.GridLayout10 = uigridlayout(app.PlayerGameBoardPanel);
            app.PlayerGameBoardUIAxes = uiaxes(app.GridLayout10);
            app.PlayerGameBoardUIAxes.XTick = [];
            app.PlayerGameBoardUIAxes.YTick = [];
            app.PlayerGameBoardUIAxes.ZTickLabel = '';
            app.ShipsChoicePanel = uipanel(app.GridLayout4);
            app.ShipsChoicePanel.Title = 'VÝBĚR TVÝCH LODÍ';
            app.GridLayout7 = uigridlayout(app.ShipsChoicePanel);
            app.GridLayout9 = uigridlayout(app.GridLayout7);
            app.RemoveAllAddedShipsButton = uibutton(app.GridLayout9, 'push');
            app.RemoveAllAddedShipsButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllAddedShipsButtonPushed, true);
            app.RemoveAllAddedShipsButton.Text = 'Odebrat všechny přidané lodě';
            app.RemoveLastAddedShipButton = uibutton(app.GridLayout9, 'push');
            app.RemoveLastAddedShipButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLastAddedShipButtonPushed, true);
            app.RemoveLastAddedShipButton.Text = 'Odebrat poslední přidanou loď';
            app.AddShipButton = uibutton(app.GridLayout9, 'push');
            app.AddShipButton.ButtonPushedFcn = createCallbackFcn(app, @AddShipButtonPushed, true);
            app.AddShipButton.Text = 'Přidat loď na herní desku';
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.PlayerBoardShipOrientation = uidropdown(app.GridLayout8);
            app.PlayerBoardShipOrientation.Items = {'Nahoru', 'Dolů', 'Doleva', 'Doprava'};
            app.PlayerBoardShipOrientation.ItemsData = [1 2 3 4];
            app.PlayerBoardShipOrientation.Value = 1;
            app.SloupecdeskyDropDownLabel = uilabel(app.GridLayout8);
            app.SloupecdeskyDropDownLabel.Text = 'Sloupec desky';
            app.dekdeskyDropDownLabel = uilabel(app.GridLayout8);
            app.dekdeskyDropDownLabel.Text = 'Řádek desky';
            app.PlayerBoardRowDropdown = uidropdown(app.GridLayout8);
            app.OrientacelodiDropDownLabel = uilabel(app.GridLayout8);
            app.OrientacelodiDropDownLabel.Text = 'Orientace lodi';
            app.PlayerBoardColumnDropdown = uidropdown(app.GridLayout8);
            app.ShipsListBox = uilistbox(app.GridLayout7);
            app.ShipsListBox.ValueChangedFcn = createCallbackFcn(app, @ShipsListBoxValueChanged, true);
            app.ShipsListBox.Value = {};
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.GameStatePanel = uipanel(app.GridLayout3);
            app.GameStatePanel.Title = 'STAV HRY';
            app.GridLayout6 = uigridlayout(app.GameStatePanel);
            app.Label_2 = uilabel(app.GridLayout6);
            app.Label_2.Text = '';
            app.GameStateArea = uitextarea(app.GridLayout6);
            app.GameStateArea.Editable = 'off';
            app.GameMenuPanel = uipanel(app.GridLayout3);
            app.GameMenuPanel.Title = 'MENU HRY';
            app.GridLayout5 = uigridlayout(app.GameMenuPanel);
            app.ResetButton = uibutton(app.GridLayout5, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StartGameButton = uibutton(app.GridLayout5, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Zahaj hru';
            app.MadeMovesHistoryTab = uitab(app.TabGroup);
            app.MadeMovesHistoryTab.Title = 'HISTORIE PROVEDENÝCH TAHŮ';
            app.GridLayout13 = uigridlayout(app.MadeMovesHistoryTab);
            app.MadeMovesHistoryTextArea = uitextarea(app.GridLayout13);
            app.MadeMovesHistoryTextArea.Editable = 'off';
            app.MadeMovesHistoryTextArea.Placeholder = 'Zde se zobrazí všechny vaše a nepřítelovy tahy během této aktuální hry';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = battleShipsGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end